classdef GammaDesk < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        LoadDataButton         matlab.ui.control.Button
        FilenameLabel          matlab.ui.control.Label
        DetectorTypeLabel      matlab.ui.control.Label
        ScintillatorTypeLabel  matlab.ui.control.Label
        Label                  matlab.ui.control.Label
        Label_2                matlab.ui.control.Label
        Label_3                matlab.ui.control.Label
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            %read text file-----------------------------
            [filename,path] = uigetfile("*.txt");
            foreground_data = readmatrix(filename);
            app.Label.Text = filename;
            %-------------------------------------------
            %process data-------------------------------
            x = 1:1:2048;
            y = foreground_data(1:2048,1)+65536.*foreground_data(1:2048,2);
            %-------------------------------------------
            %plot measured data-------------------------
            plot(app.UIAxes,x',y);
            app.UIAxes.Title.String = filename;
            app.UIAxes.YScale = 'log';
            app.UIAxes.XLim = [0 2048];
            app.UIAxes.XMinorGrid = 'on';
            app.UIAxes.YMinorGrid = 'on';
            %-------------------------------------------
            
            app.Label_2.Text = "AFRB-S4N44-C013";
            app.Label_3.Text = "BC412_D50xH60";
            
        end

        % Callback function
        function LoadBackgroundButtonPushed(app, event)
            [filename,path] = uigetfile("*.txt");
            background_data = readmatrix(filename);
            app.Label.Text = filename;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.FilenameLabel = uilabel(app.UIFigure);
            app.FilenameLabel.Text = 'File name';
            app.DetectorTypeLabel = uilabel(app.UIFigure);
            app.DetectorTypeLabel.Text = 'Detector Type';
            app.ScintillatorTypeLabel = uilabel(app.UIFigure);
            app.ScintillatorTypeLabel.Text = 'Scintillator Type';
            app.Label = uilabel(app.UIFigure);
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_3 = uilabel(app.UIFigure);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'Channel Number')
            ylabel(app.UIAxes, 'Count')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GammaDesk

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end